classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        Image5_5                        matlab.ui.control.Image
        Image5_4                        matlab.ui.control.Image
        Label3_7                        matlab.ui.control.Label
        DPrintableModelGenerationLabel  matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        Label3_6                        matlab.ui.control.Label
        FeatureExtractionLabel          matlab.ui.control.Label
        Image5_2                        matlab.ui.control.Image
        PartialVolumeEstimationLabel    matlab.ui.control.Label
        Label3_5                        matlab.ui.control.Label
        Label3_4                        matlab.ui.control.Label
        TumourSegmentationLabel         matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        KeyFeaturesLabel                matlab.ui.control.Label
        NeuroSculptBrainTumorSurgicalPlanningToolLabel_2  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        OverivewLabel                   matlab.ui.control.Label
        InstructionsTab                 matlab.ui.container.Tab
        Label3_11                       matlab.ui.control.Label
        Step4FeatureAnalysisLabel       matlab.ui.control.Label
        Image6_3                        matlab.ui.control.Image
        Label3_10                       matlab.ui.control.Label
        Step3Generate3DPrintableModelLabel  matlab.ui.control.Label
        Image6_2                        matlab.ui.control.Image
        Label3_9                        matlab.ui.control.Label
        Step2TumourSegmentationLabel    matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Label3_8                        matlab.ui.control.Label
        Step1UploadMRIFilesLabel        matlab.ui.control.Label
        InstructionsLabel               matlab.ui.control.Label
        NeuroSculptBrainTumorSurgicalPlanningToolLabel_3  matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        ShneLabel_3                     matlab.ui.control.Label
        Image3_3                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        UploadFileTab                   matlab.ui.container.Tab
        ChangeButton_features           matlab.ui.control.Button
        FeaturesDropDown                matlab.ui.control.DropDown
        FeaturesDropDownLabel           matlab.ui.control.Label
        TumorSegmentationLabel          matlab.ui.control.Label
        tumor_seg                       matlab.ui.control.Button
        FeaturesLabel                   matlab.ui.control.Label
        features                        matlab.ui.control.Button
        ChangeButton                    matlab.ui.control.Button
        SelectAtlasDropDown             matlab.ui.control.DropDown
        SelectAtlasDropDownLabel        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        STLLayersSpinner                matlab.ui.control.Spinner
        STLLayersSpinner_2Label         matlab.ui.control.Label
        NeuroSculptBrainTumorSurgicalPlanningToolLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        PartialVolumeLabel              matlab.ui.control.Label
        partial_vol                     matlab.ui.control.Button
        DownloadSTLLabel                matlab.ui.control.Label
        stl                             matlab.ui.control.Button
        UploadFileLabel                 matlab.ui.control.Label
        Label3_3                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        upload                          matlab.ui.control.Button
    end


    properties (Access = private)
        A; % Description
        Property % Description
        imageDir
        network
        scan
        predicted
        flair_info
        t1ceInfo
        segInfo
        flair
        flairFile = ''; % Description
        t1ceFile = ''; % Description
        segFile = ''; % Description
        flairFileread
        t1ceFileread
        segFileread
        resliced_seg
        resliced_tc1e
        output
        output_tumor
        seg_image
        seg_imageread
        negative
        ButtonuploadPressed = false; % Description
        Buttontumor_segPressed = false;
        Buttonpartial_volPressed = false;
        resizedFlair;
        resizedT1ce;
        resizedMask;
        out1seg_resized = '';
        out1seg_resized_zeroed = '';
    end

    methods (Access = private)

        function confirmClose(app, ~, event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                delete(app);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

        end

        % Button pushed function: upload
        function uploadPushed(app, event)
            %Checks if  button has been clicked
            app.ButtonuploadPressed = true;
            % Prompt user to select a folder
            folder = uigetdir;
            % If no folder is selected, return
            if folder == 0
                return;
            end
            % Get all files in the folder
            allFiles = dir(folder);
            % Initialize the required file names
            app.flairFile = '';
            app.t1ceFile = '';
            app.segFile = '';
            % Loop through all files and find the required ones
            for file = allFiles'
                [~, name, ext] = fileparts(file.name);
                if contains(name, '_flair') && strcmpi(ext, '.nii')
                    app.flairFile = fullfile(folder, file.name);
                elseif contains(name, '_t1ce') && strcmpi(ext, '.nii')
                    app.t1ceFile = fullfile(folder, file.name);
                elseif contains(name, '_seg') && strcmpi(ext, '.nii')
                    app.segFile = fullfile(folder, file.name);
                end
            end

%             volflair = niftiread (app.flairFile);
%             volseg = niftiread (app.segFile);
%             volumeViewer(volflair,  volseg)

            % Check if all required files are found
            if ~isempty(app.flairFile) && ~isempty(app.t1ceFile) && ~isempty(app.segFile)
                app.TextArea.Value = 'All files have been uploaded.';
                pause(3)
            else
                app.TextArea.Value = 'Error: Not all required files were found in the selected folder.';
            end

        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Display confirmation dialog box
            msg = 'Are you sure you want to close?';
            uiconfirm(app.UIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
        end

        % Button pushed function: stl
        function stlPushed(app, event)
            if ~app.ButtonuploadPressed
                msgbox('Sorry, You have not uploaded any files into the program. Please upload files and then proceed ', 'Warning', 'warn');

            else


                if ~app.Buttontumor_segPressed
                    msgbox('Thanks for uploading the files! To get the Partial Volume values you need to segment the tumor ', 'Warning', 'warn');

                else

                    if ~app.Buttonpartial_volPressed
                        msgbox('To get the .STL file, you need to get the registered brain and tumor from the Partial Volume ', 'Warning', 'warn');

                    else


                        if app.STLLayersSpinner.Value == 0

% Load registered brain and tumor data
                        tumor_nifti = niftiread('output_registered_tumor.nii.gz');
                        brain_nifti = niftiread('output_image.nii.gz');
                        negative_brain_data = brain_nifti;
                        negative_brain_data(tumor_nifti > 0) = 0;

                        % Use the info of an existing NIfTI file as reference
                        brain_info = niftiinfo('output_image.nii.gz');
                        niftiwrite(negative_brain_data, 'negativespace_T1c_brain.nii.gz', brain_info);
                        volshow(negative_brain_data);
                        me = medicalVolume('negativespace_T1c_brain.nii.nii');

                        layers = app.STLLayersSpinner.Value;
                        me.Voxels(:,:,round(linspace(1,128,layers))) = 0;

                        R = me.VolumeGeometry;
                        isovalue = 100; % Adjust the isovalue for extracting the isosurface
                        [faces, vertices] = extractIsosurface(me.Voxels, isovalue);
                        triInstrinsic = triangulation(double(faces), double(vertices));
                        I = vertices(:,1);
                        J = vertices(:,2);
                        K = vertices(:,3);

                        [X, Y, Z] = intrinsicToWorld(R, I, J, K);
                        verticesPatientCoords = [X, Y, Z];
                        triPatient = triangulation(double(faces), double(verticesPatientCoords));

                        [file, path] = uiputfile('tumor.stl');
                        stlwrite(triPatient, fullfile(path, file));

                        viewerIntrinsic = viewer3d;
                        obj = images.ui.graphics3d.Surface(viewerIntrinsic, Data=triInstrinsic, Color=[0.88, 0.84, 0.71], Alpha=0.9);

                        else 


                            % Load registered brain and tumor data
tumor_nifti = niftiread(app.segFile);
brain_nifti = niftiread(app.flairFile);

% Load the NIfTI files
output1seg =  tumor_nifti;
originalFlair = brain_nifti;
resizedOut = imresize3(output1seg, size(brain_nifti), 'cubic');
% Zero out necrosis and edema
resizedOut(resizedOut == 1) = 0; % zero out necrosis
% resizedOut(resizedOut == 2) = 0; % zero out edema

% Visualize original flair volume
%volumeViewer(originalFlair, resizedOut);

negative_brain_data = brain_nifti;
negative_brain_data(tumor_nifti > 0) = 0;


% Get the indices of the non-zero elements (tumor voxels)
[row, col, z] = ind2sub(size(tumor_nifti), find(tumor_nifti > 0));

% Find the unique Z-coordinates of the tumor voxels
unique_z = unique(z);


% Use the info of an existing NIfTI file as reference
brain_info = niftiinfo(app.flairFile);
niftiwrite(negative_brain_data, 'negativespace_T1c_brain.nii.gz', brain_info);
volshow(negative_brain_data);
me = medicalVolume('negativespace_T1c_brain.nii.nii');

% Set the number of layers
layers = app.STLLayersSpinner.Value; % You can adjust the number of layers as needed

% Find the middle of the tumor along the Z-axis
middle_tumor_z = round(mean(unique_z));

% Divide the range of Z-coordinates above and below the middle of the tumor into layers
layer_indices_below = round(linspace(1, middle_tumor_z, layers + 1));
layer_indices_above = round(linspace(middle_tumor_z + 1, size(me.Voxels, 3), layers + 1));

% Combine the layer indices
layer_indices = [layer_indices_below(1:end-1), layer_indices_above];

% Generate and save the slices
for layer = 1:(2 * layers)
    % Calculate the start and end indices of the current slice
    start_index = layer_indices(layer);
    end_index = layer_indices(layer + 1) - 1;

    % Extract the current slice of the brain data
    slice_data = me.Voxels(:, :, start_index:end_index);

    R = me.VolumeGeometry;
    faces = [];
    vertices = [];

    % Iterate through a range of isovalues to find a suitable one
    for isovalue = 80:120
        [faces, vertices] = extractIsosurface(slice_data, isovalue);
        if ~isempty(faces)
            break;
        end
    end

    if isempty(faces)
        fprintf('Warning: Empty face list for layer %d, skipping.\n', layer);
        continue;
    end

    triInstrinsic = triangulation(double(faces), double(vertices));
    I = vertices(:,1);
    J = vertices(:,2);
    K = vertices(:,3);

    [X, Y, Z] = intrinsicToWorld(R, I, J, K);
    verticesPatientCoords = [X, Y, Z];
    triPatient = triangulation(double(faces), double(verticesPatientCoords));

    % Save each slice as a separate .STL file
    [file, path] = uiputfile(sprintf('brain_slice_%02d.stl', layer));
    stlwrite(triPatient, fullfile(path, file));

    viewerIntrinsic = viewer3d;
    obj = images.ui.graphics3d.Surface(viewerIntrinsic, Data=triInstrinsic, Color=[0.88, 0.84, 0.71], Alpha=0.9);
end

%%
% Load tumor data
% Load tumor data
tumor_nifti = niftiread(app.segFile);

% Load reference NIfTI file for geometry information
ref_nifti_info = niftiinfo(app.flairFile);
% Extract the isosurface and create the STL file
R = ref_nifti_info.Transform;
isovalue = 0.9; % Adjust the isovalue for extracting the isosurface
[faces, vertices] = extractIsosurface(tumor_nifti, isovalue);

% Check if faces and vertices are empty
if isempty(faces) || isempty(vertices)
    error('extractIsosurface returned empty faces or vertices.');
end

triIntrinsic = triangulation(double(faces), double(vertices));

I = vertices(:,1);
J = vertices(:,2);
K = vertices(:,3);

vertices_intrinsic = [I, J, K];
verticesPatientCoords = transformPointsForward(R, vertices_intrinsic);
triPatient = triangulation(double(faces), double(verticesPatientCoords));

stl_filename = 'final_tumor.stl';
stlwrite(triPatient, stl_filename);

                        end 
                    end
                end
            end
        end

        % Button pushed function: partial_vol
        function partial_volPushed(app, event)


            if ~app.ButtonuploadPressed
                msgbox('Sorry, You have not uploaded any files into the program. Please upload files and then proceed ', 'Warning', 'warn');

            else


                if ~app.Buttontumor_segPressed
                    msgbox('Thanks for uploading the files! To get the Partial Volume values you need to segment the tumor ', 'Warning', 'warn');

                else
                    app.Buttonpartial_volPressed = true;
                    oldnifti = niftiread(app.t1ceFile);
                    newnifti = oldnifti;
                    dims_nifti = size(oldnifti);
                    dims_nifti_new = size(newnifti);

                    newnifti = oldnifti(30:dims_nifti(1)-29,:,:);
                    newnifti = oldnifti(30:dims_nifti(1)-29,12:dims_nifti(1)-11,:);
                 
                    slice1 = oldnifti(30:30,:,:);

                    pad_size = [0 0 13];
                    padded_img = padarray(newnifti, pad_size, 0, 'both');
                    pad_size = [0 0 1];
                    newnifti = padarray(padded_img, pad_size, 0, 'post');
                    volshow(newnifti);

                    niftiwrite(newnifti, 'Cropped_samevox_T1CE')
                    app.resliced_tc1e = 'Cropped_samevox_T1CE';


                    % Check if all required files are found
                    if ~isempty(app.resliced_tc1e)
                        app.TextArea.Value = 'T1CE Resliced';
                    else
                        app.TextArea.Value = 'Error';
                    end
                    pause(3)


                    %%
                    oldnifti_seg = niftiread(app.out1seg_resized_zeroed);
                    newnifti_seg = oldnifti_seg;
                    dims_nifti_seg = size(oldnifti_seg);
                    dims_nifti_new_seg = size(newnifti_seg);

                    newnifti_seg = oldnifti_seg(30:dims_nifti_seg(1)-29,:,:);
                    newnifti_seg = oldnifti_seg(30:dims_nifti_seg(1)-29,12:dims_nifti_seg(1)-11,:);
                   
                    slice1_seg = oldnifti_seg(30:30,:,:);

                    pad_size = [0 0 13];
                    padded_img = padarray(newnifti_seg, pad_size, 0, 'both');
                    pad_size = [0 0 1];
                    newnifti_seg = padarray(padded_img, pad_size, 0, 'post');
                    volshow(newnifti_seg);

                    niftiwrite(newnifti_seg, 'Cropped_samevox_SegT')
                    app.resliced_seg = 'Cropped_samevox_SegT';


                    % Check if all required files are found
                    if ~isempty(app.resliced_seg)
                        app.TextArea.Value = 'Segmented Resliced';
                    else
                        app.TextArea.Value = 'Error';
                    end

                    pause(2);


                    %%
                    % Execute the Python script from MATLAB
                    [status, result] = system('python importSimpleITKassitk.py');


                    % Check if the Python script executed successfully
                    if status == 0
                        disp('Python script executed successfully.');
                    else
                        disp('Error in executing the Python script.');
                        disp(result);
                        return;
                    end

                    % Load the output files into MATLAB
                    output_image = niftiread('output_image.nii.gz');
                    output_registered_tumor = niftiread('output_registered_tumor.nii.gz');

                    app.output = output_image;
                    app.output_tumor = output_registered_tumor;

                    % Select a slice to display (e.g., the middle slice)
                    slice_idx = round(size(output_image, 3) / 2);
                    brain_slice = output_image(:, :, slice_idx);
                    tumor_slice = output_registered_tumor(:, :, slice_idx);


                    if ~isempty(app.resliced_seg) && ~isempty(app.resliced_tc1e) && ~isempty(app.output) && ~isempty(app.output_tumor)
                        app.TextArea.Value = 'Brain and Tumor have been registered on the MNI atlas ';
                        pause(3);
                        app.TextArea.Value = 'Loading Atlas information...';
                        pause(1);
                    else
                        app.TextArea.Value = 'Error';
                    end
                    pause(3);

                    %%
                    tumour_atlas = app.output_tumor; %Loading the Registered Tumor
                    %                     sphere = app.output_tumor;

                    sphere_path = "C:\Users\tahak\Desktop\Capstone\output_registered_tumor.nii.gz";
                    sphere = niftiread(sphere_path);

                    switch app.SelectAtlasDropDown.Value
                        case 'Juelich Histological Atlas'
                            a=1;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('Juelich-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'Harvard-Oxford Cortical Structural Atlas'
                            a=2;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('HarvardOxford-cort-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'Harvard-Oxford Subcortical Structural Atlas'
                            a=3;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('HarvardOxford-sub-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'JHU ICBM-DTI-81 White Matter labels'
                            a=4;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('JHU-ICBM-labels-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'JHU White Matter tractography Atlas'
                            a=5;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('JHU-ICBM-labels-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'Oxford Thalamic Connectivity Atlas'
                            a=6;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('HarvardOxford-cort-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'Cerebellar Atlas in MNI152 after FLIRT'
                            a=7;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'Cerebellar Atlas in MNI152 after FNIRT'
                            a=8;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                        case 'MNI Structural Atlas'
                            a=9;

                            %Histological atlas (to change based on input - see if possible else
                            %overlay with harvard oxford or MNI)
                            atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                            %has to be of this format
                            volumeViewer(tumour_atlas, atlas)

                    end


                    roi = flip(sphere,1);
                    app.TextArea.Value = mni2atlas(roi,a,50).label;
                end
            end

        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.TextArea.Value = 'Updating...';

            pause(3);
            tumour_atlas = app.output_tumor; %Loading the Registered Tumor
            sphere_path = "C:\Users\tahak\Desktop\Capstone\output_registered_tumor.nii.gz";
            sphere = niftiread(sphere_path);


            switch app.SelectAtlasDropDown.Value
                case 'Juelich Histological Atlas'
                    a=1;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('Juelich-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'Harvard-Oxford Cortical Structural Atlas'
                    a=2;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('HarvardOxford-cort-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'Harvard-Oxford Subcortical Structural Atlas'
                    a=3;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('HarvardOxford-sub-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'JHU ICBM-DTI-81 White Matter labels'
                    a=4;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('JHU-ICBM-labels-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'JHU White Matter tractography Atlas'
                    a=5;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('JHU-ICBM-labels-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'Oxford Thalamic Connectivity Atlas'
                    a=6;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('HarvardOxford-cort-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'Cerebellar Atlas in MNI152 after FLIRT'
                    a=7;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'Cerebellar Atlas in MNI152 after FNIRT'
                    a=8;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

                case 'MNI Structural Atlas'
                    a=9;

                    %Histological atlas (to change based on input - see if possible else
                    %overlay with harvard oxford or MNI)
                    atlas = niftiread('MNI-maxprob-thr0-1mm.nii.gz');
                    %has to be of this format
                    volumeViewer(tumour_atlas, atlas)

            end


            roi = flip(sphere,1);
            app.TextArea.Value = mni2atlas(roi,a,50).label;

        end

        % Button pushed function: features
        function featuresPushed(app, event)
            function slice_str = format_slice_indices(slice_indices)
                slice_str = sprintf('%d, ', slice_indices(1:end-1));
                slice_str = [slice_str, sprintf('%d', slice_indices(end))];
            end                       

if ~app.ButtonuploadPressed
                            msgbox('Sorry, You have not uploaded any files into the program. Please upload files and then proceed ', 'Warning', 'warn');
            
                        else
            
            
                            if ~app.Buttontumor_segPressed
                                msgbox('Thanks for uploading the files! To get the Feature values you need to segment the tumor before ', 'Warning', 'warn');
            
                            else
            sliceNum = 50; % Change this number ot preview different individual slices

            %% READ ME!!
            % When executing this script, upon the first prompt open the flair.nii file for a patient,
            % and upon the second prompt open the segmented tumor seg.nii file.

            % Also note the tumor and brain volume outputs are are estimates. These
            % values will vary depending on the density of the tumor and brain.

            %% Loading Brain Volume
            Brain= niftiread(app.flairFile);
            %% Loading Tumor Volume
            Tumor = niftiread(app.out1seg_resized_zeroed);
            %% Finding Volume of Brain
                        % Calculating Shortest Distance

             app.TextArea.Value = '';
 app.TextArea.Value = 'Updating...';

            count = 0;
            format short g
            for i = 1:240
                for j = 1:240
                    for k =1:155
                        if Brain(i,j,k) ~= 0
                            count = count +1;
                        end
                    end
                end
            end
            numOfVoxels_mm = count;
            numOfVoxels_cm = numOfVoxels_mm/1000;
            numOfVoxels_ounce = count/29570;
            numOfVoxels_grams = numOfVoxels_cm*1.1;

            %% Calculating Shortest Distance
            %% Finding Outline of Brain and Tumor
            brainBin  = Brain>0;
            brainSlice = brainBin;

            gBrain = strel('disk',1);
            esBrain=imerode(brainSlice,gBrain);
            esBrain=brainSlice-esBrain;

            gTumor = strel('disk',1);
            esTumor=imerode(Tumor,gTumor);
            esTumor=Tumor-esTumor;


            % XY-Plane (iterating through Z values)
            for z = 1:155
                shortestDistanceZ(z) = 1000000000;
                tumorSlice = esTumor(:,:,z);
                brainSlice = esBrain(:,:,z);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceZ(z))
                            shortestDistanceZ(z)= newDistance;
                            angleZ(z) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end

            % X-Direction
            for x = 1:240
                shortestDistanceX(x) = 1000000000;
                tumorSlice = esTumor(x,:,:);
                brainSlice = esBrain(x,:,:);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceX(x))
                            shortestDistanceX(x)= newDistance;
                            angleX(x) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end

            % Y-Direction
            for y = 1:240
                shortestDistanceY(y) = 1000000000;
                tumorSlice = esTumor(:,y,:);
                brainSlice = esBrain(:,y,:);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceY(y))
                            shortestDistanceY(y)= newDistance;
                            angleY(y) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end
            % Printing Shortest Distance Metrics
            Shortest_Distance_minYZ= min(shortestDistanceX);
            Shortest_Distance_minXZ= min(shortestDistanceY);
            Shortest_Distance_minXY= min(shortestDistanceZ);
            Shortest_Distance_min_array= [Shortest_Distance_minYZ,Shortest_Distance_minXZ,Shortest_Distance_minXY];
            Shortest_Distance_min_overall= min(Shortest_Distance_min_array);


            SlicesYZ = find(shortestDistanceX == min(Shortest_Distance_minYZ));
            SlicesXZ = find(shortestDistanceY == min(Shortest_Distance_minXZ));
            SlicesXY = find(shortestDistanceZ == min(Shortest_Distance_minXY));

            AngleYZ = rad2deg(angle(SlicesYZ));
            AngleXZ = rad2deg(angle(SlicesXZ));
            AngleXY = rad2deg(angle(SlicesXY));


            %% Finding Volume of Tumor
            a=2;
            count2 = 0;
            format short g
            for i = 1:240
                for j = 1:240
                    for k = 1:155
                        if Tumor(i, j, k) ~= 0
                            count2 = count2 + 1;
                        end
                    end
                end
            end
            numOfVoxels_mm2 = count2;
            numOfVoxels_cm2 = numOfVoxels_mm2 / 1000;
            numOfVoxels_ounce2 = count2 / 29570;
            numOfVoxels_grams2 = numOfVoxels_cm2 * 1.1;
            switch app.FeaturesDropDown.Value
                case 'Volume of Brain'
                    a=1;


                    % Update TextArea1 Value with proper formatting
                    resultText = sprintf(['------------------Volume of Brain--------------------\n', ...
                        'The approximate volume of the brain in mm^3 is: %0.2f\n', ...
                        'The approximate volume of the brain in Ounces is: %0.2f\n', ...
                        'The approximate mass of the brain (based on average tissue density) in grams is: %0.2f\n'], ...
                        numOfVoxels_mm, numOfVoxels_ounce, numOfVoxels_grams);

                    app.TextArea.Value = resultText;

                case 'Volume of Tumor'


                    % Update TextArea1 Value with proper formatting
                    tumorText = sprintf(['------------------Volume of Tumor--------------------\n', ...
                        'The approximate volume of the Tumor in mm^3 is: %0.2f\n', ...
                        'The approximate volume of the Tumor in Ounces is: %0.2f\n', ...
                        'The approximate mass of the Tumor in grams (based on average tissue density) is: %0.2f\n'], ...
                        numOfVoxels_mm2, numOfVoxels_ounce2, numOfVoxels_grams2);

                    % Append tumorText to the existing resultText in TextArea1
                    app.TextArea.Value = [app.TextArea.Value, tumorText];

                case 'Percentage volume of Tumor within Brain'
                    a=3;

                    % Finding Percent Volume of Tumor Within Brain
                    percent = ((numOfVoxels_mm2 / numOfVoxels_mm) * 100);

                    % Update TextArea1 Value with proper formatting
                    percentText = sprintf(['---------------Percentage of Tumor in Brain-----------------\n', ...
                        'The Percent volume that the tumor occupies within the brain is: %0.2f%%\n'], ...
                        percent);

                    % Append percentText to the existing resultText in TextArea1
                    app.TextArea.Value = [app.TextArea.Value, percentText];

                case 'Finding the shortest distance from the Tumor to the Brain'
                    a=4;


                    % app.TextArea.Value = [app.TextArea.Value, char(10), '---------------Finding the Shortest Distance from the Tumor to the Brain-----------------'];
                    % app.TextArea.Value = [app.TextArea.Value, newline, sprintf('The overall shortest distance between the tumor and brain in milimeters is: %.2f', Shortest_Distance_min_overall)];
                    % app.TextArea.Value = [app.TextArea.Value, newline, sprintf('The shortest distance between the tumor and brain in the YZ plane in milimeters is: %.2f', Shortest_Distance_minYZ)];
                    % app.TextArea.Value = [app.TextArea.Value, newline, sprintf('The shortest distance between the tumor and brain in the XZ plane in milimeters is: %.2f', Shortest_Distance_minXZ)];
                    % app.TextArea.Value = [app.TextArea.Value, newline, sprintf('The shortest distance between the tumor and brain in the XY plane in milimeters is: %.2f', Shortest_Distance_minXY)];
                    %
                    % app.TextArea.Value = [app.TextArea.Value, char(10), '---------------Finding the Angle of the Line of Shortest Distance from the Tumor to the Brain-----------------'];
                    %
                    % if AngleXY == 0
                    %     app.TextArea.Value = [app.TextArea.Value, newline, 'In the XY plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)'];
                    % else
                    %     app.TextArea.Value = [app.TextArea.Value, newline, sprintf('In the XY plane the angle between the tumor and brain along the line of shortest distance is: %.2f', AngleXY)];
                    % end
                    %
                    % if AngleXZ == 0
                    %     app.TextArea.Value = [app.TextArea.Value, newline, 'In the XZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)'];
                    % else
                    %     app.TextArea.Value = [app.TextArea.Value, newline, sprintf('In the XZ plane the angle between the tumor and brain along the line of shortest distance is: %.2f', AngleXZ)];
                    % end
                    %
                    % if AngleYZ == 0
                    %     app.TextArea.Value = [app.TextArea.Value, newline, 'In the YZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)'];
                    % else
                    %     app.TextArea.Value = [app.TextArea.Value, newline, sprintf('In the YZ plane the angle between the tumor and brain (in degrees) along the line of shortest distance is: %.2f', AngleYZ)];
                    % end
                    %


                    % app.TextArea.Value = [app.TextArea.Value newline sprintf('The shortest distance occurs in the YZ (Sagital) plane at slices:') newline sprintf('%d, ', SlicesYZ(1:end-1)) sprintf('%d', SlicesYZ(end))];
                    % app.TextArea.Value = [app.TextArea.Value newline sprintf('The shortest distance occurs in the XZ (Coronal) plane at slices:') newline sprintf('%d, ', SlicesXZ(1:end-1)) sprintf('%d', SlicesXZ(end))];
                    % app.TextArea.Value = [app.TextArea.Value newline sprintf('The shortest distance occurs in the XY (Axial) plane at slices:') newline sprintf('%d, ', SlicesXY(1:end-1)) sprintf('%d', SlicesXY(end))];
                    %
                    % Build the output string
                    output_str = sprintf('---------------Finding the Shortest Distance from the Tumor to the Brain-----------------\n');
                    output_str = [output_str, sprintf('The overall shortest distance between the tumor and brain in milimeters is: %.2f\n', Shortest_Distance_min_overall)];
                    output_str = [output_str, sprintf('The shortest distance between the tumor and brain in the YZ plane in milimeters is: %.2f\n', Shortest_Distance_minYZ)];
                    output_str = [output_str, sprintf('The shortest distance between the tumor and brain in the XZ plane in milimeters is: %.2f\n', Shortest_Distance_minXZ)];
                    output_str = [output_str, sprintf('The shortest distance between the tumor and brain in the XY plane in milimeters is: %.2f\n', Shortest_Distance_minXY)];
                    output_str = [output_str, sprintf('\n')]; % This adds an empty line.
                    output_str = [output_str, sprintf('---------------Finding the Angle of the Line of Shortest Distance from the Tumor to the Brain-----------------\n')];

                    if AngleXY == 0
                        output_str = [output_str, sprintf('In the XY plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
                    else
                        output_str = [output_str, sprintf('In the XY plane the angle between the tumor and brain along the line of shortest distance is: %.2f\n', AngleXY)];
                    end

                    if AngleXZ == 0
                        output_str = [output_str, sprintf('In the XZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
                    else
                        output_str = [output_str, sprintf('In the XZ plane the angle between the tumor and brain along the line of shortest distance is: %.2f\n', AngleXZ)];
                    end

                    if AngleYZ == 0
                        output_str = [output_str, sprintf('In the YZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
                    else
                        output_str = [output_str, sprintf('In the YZ plane the angle between the tumor and brain (in degrees) along the line of shortest distance is: %.2f\n', AngleYZ)];
                    end
                    output_str = [output_str, sprintf('\n')]; % This adds an empty line.
                    output_str = [output_str, sprintf('The shortest distance occurs in the YZ (Sagital) plane at slices: %s\n', format_slice_indices(SlicesYZ))];
                    output_str = [output_str, sprintf('\n')]; % This adds an empty line.
                    output_str = [output_str, sprintf('The shortest distance occurs in the XZ (Coronal) plane at slices: %s\n', format_slice_indices(SlicesXZ))];
                    output_str = [output_str, sprintf('\n')]; % This adds an empty line.
                    output_str = [output_str, sprintf('The shortest distance occurs in the XY (Axial) plane at slices: %s\n', format_slice_indices(SlicesXY))];

                    app.TextArea.Value = [app.TextArea.Value, output_str];


                case 'Display montage plot and overlay Volume'
                    a=5;
                    %% Displaying a Montage of all Slices with shortest Distance in the Axial Plane
                    % Specify the slice number range
                    num = numel(SlicesXY);
                    startSlice = SlicesXY(1); % start slice number
                    endSlice = SlicesXY(num); % end slice number

                    if endSlice==startSlice
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        gBrain = strel('disk',1);
                        esBrain=imerode(brainSlice,gBrain);
                        esBrain=brainSlice-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(Tumor,gTumor);
                        esTumor=Tumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,:,startSlice), esBrain(:,:,startSlice));
                        imshow(C)

                    elseif endSlice~=startSlice
                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            brainBin  = Brain > 0;
                            brainSlice = brainBin;

                            % Perform erosion on brainSlice
                            gBrain = strel('disk',1);
                            esBrain=imerode(brainSlice,gBrain);
                            esBrain=brainSlice-esBrain;

                            % Perform erosion on Tumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(Tumor,gTumor);
                            esTumor=Tumor-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor(:,:,sliceNumber), esBrain(:,:,sliceNumber));

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with Shortest Distance in the Axial Plane');
                    end

                    %% Displaying a Montage of all Slices with shortest Distance in the Coronal Plane
                    % Specify the slice number range
                    num = numel(SlicesXZ);
                    startSlice = SlicesXZ(1); % start slice number
                    endSlice = SlicesXZ(num); % end slice number
                    if endSlice==startSlice
                        coronalSlice = squeeze(Brain(:,sliceNumber,:));
                        coronalSliceTumor = squeeze(Tumor(:,sliceNumber,:));
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        coronalSliceBrain = squeeze(brainSlice(:,sliceNumber,:));
                        gBrain = strel('disk',1);
                        esBrain=imerode(coronalSliceBrain,gBrain);
                        esBrain=coronalSliceBrain-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(coronalSliceTumor,gTumor);
                        esTumor=coronalSliceTumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,startSlice,:), esBrain(:,startSlice,:));
                        imshow(C)
                    elseif endSlice~=startSlice

                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            % Extract the coronal slice of brain
                            coronalSliceBrain = squeeze(brainSlice(:,sliceNumber,:));

                            % Perform erosion on coronalSliceBrain
                            gBrain = strel('disk',1);
                            esBrain=imerode(coronalSliceBrain,gBrain);
                            esBrain=coronalSliceBrain-esBrain;

                            % Extract the axial slice of tumor
                            coronalSliceBrain = squeeze(Tumor(:,sliceNumber,:));

                            % Perform erosion on axialSliceTumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(coronalSliceBrain,gTumor);
                            esTumor=coronalSliceBrain-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor, esBrain);

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with shortest Distance in the Coronal Plane');
                    end

                    %% Displaying a Montage of all Slices with shortest Distance in the Sagital Plane
                    % Specify the slice number range
                    num = numel(SlicesYZ);
                    startSlice = SlicesYZ(1); % start slice number
                    endSlice = SlicesYZ(num); % end slice number

                    if endSlice==startSlice
                        sagitalSlice = squeeze(Brain(sliceNumber,:,:));
                        sagitalSliceTumor = squeeze(Tumor(sliceNumber,:,:));
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        sagitalSlice = squeeze(brainSlice(sliceNumber,:,:));
                        gBrain = strel('disk',1);
                        esBrain=imerode(sagitalSlice,gBrain);
                        esBrain=sagitalSlice-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(sagitalSliceTumor,gTumor);
                        esTumor=sagitalSliceTumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,:,:), esBrain(:,:,:));
                        imshow(C)

                    elseif endSlice~=startSlice

                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            % Extract the coronal slice of brain
                            sagitalSliceBrain = squeeze(brainSlice(sliceNumber,:,:));

                            % Perform erosion on coronalSliceBrain
                            gBrain = strel('disk',1);
                            esBrain=imerode(sagitalSliceBrain,gBrain);
                            esBrain=sagitalSliceBrain-esBrain;

                            % Extract the axial slice of tumor
                            sagitalSliceBrain = squeeze(Tumor(sliceNumber,:,:));

                            % Perform erosion on axialSliceTumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(sagitalSliceBrain,gTumor);
                            esTumor=sagitalSliceBrain-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor, esBrain);

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with shortest Distance in the Sagital Plane');
                    end

                    % Opening Interactive Slice Viewer
                    overlayVolume(Brain,Tumor)

            end
                            end
                        end
        end

        % Button pushed function: tumor_seg
        function tumor_segPushed(app, event)


            function update_deepmedic_config(config_path, new_path)
                % Write the updated path to the config file
                fid = fopen(config_path, 'w');

                if fid < 0
                    fprintf('Error: Unable to open the configuration file at %s for writing\n', config_path);
                    return;
                end

                fwrite(fid, new_path);
                fclose(fid);
            end

            function [returnFlairPath, returnT1cePath, returnMaskPath] = resizeImsPre(inputFlairPath, inputt1cePath, imageName, scale)

                % Flair
                inputFlair = double(niftiread(inputFlairPath)); % read
                returnFlair = imresize3(inputFlair, scale, 'cubic'); % resize
                output_folder = 'C:\Users\tahak\Desktop\deepmedic';
                returnFlairPath = fullfile(output_folder, strcat(imageName, '_flair.nii')); % create output path
                niftiwrite(returnFlair, returnFlairPath); % write

                % T1CE
                inputt1ce = double(niftiread(inputt1cePath)); % read
                returnT1ce = imresize3(inputt1ce, scale, 'cubic'); % resize
                returnT1cePath = fullfile(output_folder, strcat(imageName, '_t1ce.nii')); % create output path
                niftiwrite(returnT1ce, returnT1cePath); % write

                % Create binary mask from T1CE
                meanIntensity = mean(returnT1ce(:)); % compute mean
                dims = size(returnT1ce); % compute dimensions of mri
                returnMask = zeros(dims); % create mask with mean as threshold
                returnMask(returnT1ce>meanIntensity) = 1;
                returnMaskPath = fullfile(output_folder, strcat(imageName, '_brainmask.nii')); % create output path
                niftiwrite(returnMask, returnMaskPath); % write

            end


            if ~app.ButtonuploadPressed
                msgbox('Sorry, You have not uploaded any files into the program. Please upload files and then proceed ', 'Warning', 'warn');
            else

                % In your MATLAB app, e.g., in a callback function:
                inputFlairPath = app.flairFile;
                inputt1cePath = app.t1ceFile;
                imageName = 'example';
                scale = 0.6; % scale factor, e.g., 0.5 for half the size


                % Call the resizeImsPre function
                [app.resizedFlair, app.resizedT1ce, app.resizedMask] = resizeImsPre(inputFlairPath, inputt1cePath, imageName, scale);

                % Check if all required files are found
                if ~isempty(app.resizedFlair) && ~isempty(app.resizedT1ce) && ~isempty(app.resizedMask)
                    app.TextArea.Value = 'Files have been resized';
                    pause(3)
                else
                    app.TextArea.Value = 'Error.';
                end

                app.Buttontumor_segPressed = true;
                % Update the DeepMedic configuration files with the new paths
                flair_config_path = 'C:\Users\tahak\Desktop\deepmedic\examples\configFiles\deepMedic\test\testChannels_flair.cfg';
                t1ce_config_path = 'C:\Users\tahak\Desktop\deepmedic\examples\configFiles\deepMedic\test\testChannels_t1c.cfg';
                brain_config_path = 'C:\Users\tahak\Desktop\deepmedic\examples\configFiles\deepMedic\test\testRoiMasks.cfg';
                update_deepmedic_config(flair_config_path, app.resizedFlair);
                update_deepmedic_config(t1ce_config_path, app.resizedT1ce);
                update_deepmedic_config(brain_config_path, app.resizedMask);

                app.TextArea.Value = 'Segmentation Algorithm Directory has been updated';
                pause(3);
                % Run the DeepMedic algorithm via Git Bash
                deepmedic_folder = 'C:\Users\tahak\Desktop\deepmedic';
                deepmedic_command = './deepMedicRun -model ./examples/configFiles/deepMedic/model/modelConfig.cfg -test ./examples/configFiles/deepMedic/test/testConfig.cfg -load ./examples/output/saved_models/trainSessionDm/deepMedic.trainSessionDm.final.2023-04-24.05.37.29.274726.model.ckpt';
                git_bash_path = 'C:\Program Files\Git\bin\bash.exe';
                bash_script = strrep(fullfile(deepmedic_folder, 'run_deepmedic.sh'), '\', '/');
    

                                app.TextArea.Value = 'Runnning Algorithm.';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm..';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm..';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm...';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm....';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.....';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm..';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm...';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm....';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.....';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......I';
                pause(1);
                aapp.TextArea.Value = 'Runnning Algorithm.......It';
                pause(1);
                aapp.TextArea.Value = 'Runnning Algorithm.......Its';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its a';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its a w';
                pause (1);
                app.TextArea.Value = 'Runnning Algorithm.......Its a wa';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its wai';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its wait';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiti';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waitin';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting g';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting ga';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting gam';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game.';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game..';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Y';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Ye';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yea';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah w';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we a';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we ar';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are g';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are go';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are goi';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are goin';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going t';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to b';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be h';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be he';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be her';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here f';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here fo';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a w';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a wh';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a whi';
                pause(2);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a whil';
                pause(1);
                app.TextArea.Value = 'Runnning Algorithm.......Its waiting game...Yeah we are going to be here for a while';
                pause(1);

                                            fid = fopen(bash_script, 'w');
                fprintf(fid, '#!/bin/bash\n');
                fprintf(fid, 'cd "%s"\n', deepmedic_folder);
                fprintf(fid, 'source ~/.bashrc\n');
                fprintf(fid, 'source ~/.bash_profile\n');
                fprintf(fid, '%s\n', deepmedic_command);
                fclose(fid);
                command = ['"' git_bash_path '" --login -i -c "bash ' bash_script '"'];
                [status, cmdout] = system(command, '-echo');
                
                                % Define source and destination paths
                source_path = 'C:\Users\tahak\Desktop\deepmedic\examples\output\predictions\testSessionDm\predictions\newSegmentation_Segm.nii.gz';
                destination_folder = 'C:\Users\tahak\Desktop\Capstone';
                destination_path = fullfile(destination_folder, 'newSegmentation_Segm.nii.gz');

                % Move the file to the MATLAB working directory
                [status, message] = movefile(source_path, destination_folder);

                seg_imageread = niftiread('newSegmentation_Segm.nii.gz');
                app.seg_image = 'newSegmentation_Segm.nii.gz';
                volshow(seg_imageread);
                Scan1 = niftiread(app.flairFile);
                volshow(Scan1);
                app.TextArea.Value = 'Success!!';
                pause(3);


                % Load the NIfTI files
                niftiFilePath = app.segFile; % Replace with the path to your output1seg NIfTI file
                output1seg = niftiread(niftiFilePath);

                originalFlairPath = app.flairFile; % Replace with the path to your originalFlair NIfTI file
                originalFlair = niftiread(originalFlairPath);


                resizedOut = imresize3(output1seg, size(Scan1), 'cubic');

                % Save the resized volume
                outputFolderPath = pwd; % Replace with the desired output folder path
                outputFileName = fullfile(outputFolderPath, 'out1seg_resized.nii');
                niftiwrite(resizedOut, outputFileName);

                % Save the output file directory to app.out1seg_resized
                app.out1seg_resized = outputFileName;

                % Visualize original flair volume
                volumeViewer(originalFlair, resizedOut);

                % Zero out necrosis and edema
                resizedOut(resizedOut == 1) = 0; % zero out necrosis
%               resizedOut(resizedOut == 2) = 0; % zero out edema

                 % Save the resized volume
                outputFolderPath1 = pwd; % Replace with the desired output folder path
                outputFileName1 = fullfile(outputFolderPath1, 'out1seg_resized_zeroed.nii');
                niftiwrite(resizedOut, outputFileName1);
                app.out1seg_resized_zeroed = outputFileName1;
            end


        end

        % Button pushed function: ChangeButton_features
        function ChangeButton_featuresPushed(app, event)
                                    if ~app.ButtonuploadPressed
                            msgbox('Sorry, You have not uploaded any files into the program. Please upload files and then proceed ', 'Warning', 'warn');
            
                        else
            
            
                            if ~app.Buttontumor_segPressed
                                msgbox('Thanks for uploading the files! To get the Feature values you need to segment the tumor before ', 'Warning', 'warn');
            
                            else
            sliceNum = 50; % Change this number ot preview different individual slices

            %% READ ME!!
            % When executing this script, upon the first prompt open the flair.nii file for a patient,
            % and upon the second prompt open the segmented tumor seg.nii file.

            % Also note the tumor and brain volume outputs are are estimates. These
            % values will vary depending on the density of the tumor and brain.
app.TextArea.Value = 'Updating...';
            %% Loading Brain Volume
            Brain= niftiread(app.flairFile);
            %% Loading Tumor Volume
            Tumor = niftiread(app.out1seg_resized_zeroed);


            pause(3);
            %% Finding Volume of Brain
            count = 0;
            format short g
            for i = 1:240
                for j = 1:240
                    for k =1:155
                        if Brain(i,j,k) ~= 0
                            count = count +1;
                        end
                    end
                end
            end
            numOfVoxels_mm = count;
            numOfVoxels_cm = numOfVoxels_mm/1000;
            numOfVoxels_ounce = count/29570;
            numOfVoxels_grams = numOfVoxels_cm*1.1;

            %% Calculating Shortest Distance
            %% Finding Outline of Brain and Tumor
            brainBin  = Brain>0;
            brainSlice = brainBin;

            gBrain = strel('disk',1);
            esBrain=imerode(brainSlice,gBrain);
            esBrain=brainSlice-esBrain;

            gTumor = strel('disk',1);
            esTumor=imerode(Tumor,gTumor);
            esTumor=Tumor-esTumor;


            % Calculating Shortest Distance
            % XY-Plane (iterating through Z values)
            for z = 1:155
                shortestDistanceZ(z) = 1000000000;
                tumorSlice = esTumor(:,:,z);
                brainSlice = esBrain(:,:,z);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceZ(z))
                            shortestDistanceZ(z)= newDistance;
                            angleZ(z) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end

            % X-Direction
            for x = 1:240
                shortestDistanceX(x) = 1000000000;
                tumorSlice = esTumor(x,:,:);
                brainSlice = esBrain(x,:,:);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceX(x))
                            shortestDistanceX(x)= newDistance;
                            angleX(x) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end

            % Y-Direction
            for y = 1:240
                shortestDistanceY(y) = 1000000000;
                tumorSlice = esTumor(:,y,:);
                brainSlice = esBrain(:,y,:);
                [row,col, value] = find(tumorSlice>0);
                [rowb,colb, valueb] = find(brainSlice>0);
                for i = 1:size(rowb)
                    brainLoc = [rowb(i), colb(i)];
                    for j = 1:size(row)
                        tumorLoc = [row(j), col(j)];
                        r = abs(rowb(i)-row(j));
                        c = abs(colb(i)-col(j));
                        newDistance = sqrt(r^2+c^2);
                        if (newDistance<=shortestDistanceY(y))
                            shortestDistanceY(y)= newDistance;
                            angleY(y) = tan((colb(i)-col(j))/(rowb(i)-row(j)));
                        end
                    end
                end
            end
            % Printing Shortest Distance Metrics
            Shortest_Distance_minYZ= min(shortestDistanceX);
            Shortest_Distance_minXZ= min(shortestDistanceY);
            Shortest_Distance_minXY= min(shortestDistanceZ);
            Shortest_Distance_min_array= [Shortest_Distance_minYZ,Shortest_Distance_minXZ,Shortest_Distance_minXY];
            Shortest_Distance_min_overall= min(Shortest_Distance_min_array);


            SlicesYZ = find(shortestDistanceX == min(Shortest_Distance_minYZ));
            SlicesXZ = find(shortestDistanceY == min(Shortest_Distance_minXZ));
            SlicesXY = find(shortestDistanceZ == min(Shortest_Distance_minXY));

            AngleYZ = rad2deg(angle(SlicesYZ));
            AngleXZ = rad2deg(angle(SlicesXZ));
            AngleXY = rad2deg(angle(SlicesXY));


            %% Finding Volume of Tumor
            a=2;
            count2 = 0;
            format short g
            for i = 1:240
                for j = 1:240
                    for k = 1:155
                        if Tumor(i, j, k) ~= 0
                            count2 = count2 + 1;
                        end
                    end
                end
            end
            numOfVoxels_mm2 = count2;
            numOfVoxels_cm2 = numOfVoxels_mm2 / 1000;
            numOfVoxels_ounce2 = count2 / 29570;
            numOfVoxels_grams2 = numOfVoxels_cm2 * 1.1;
            switch app.FeaturesDropDown.Value
                case 'Volume of Brain'
                    a=1;


                    % Update TextArea1 Value with proper formatting
                    resultText = sprintf(['------------------Volume of Brain--------------------\n', ...
                        'The approximate volume of the brain in mm^3 is: %0.2f\n', ...
                        'The approximate volume of the brain in Ounces is: %0.2f\n', ...
                        'The approximate mass of the brain (based on average tissue density) in grams is: %0.2f\n'], ...
                        numOfVoxels_mm, numOfVoxels_ounce, numOfVoxels_grams);

                    app.TextArea.Value = resultText;

                case 'Volume of Tumor'


                    % Update TextArea1 Value with proper formatting
                    tumorText = sprintf(['------------------Volume of Tumor--------------------\n', ...
                        'The approximate volume of the Tumor in mm^3 is: %0.2f\n', ...
                        'The approximate volume of the Tumor in Ounces is: %0.2f\n', ...
                        'The approximate mass of the Tumor in grams (based on average tissue density) is: %0.2f\n'], ...
                        numOfVoxels_mm2, numOfVoxels_ounce2, numOfVoxels_grams2);

                    % Append tumorText to the existing resultText in TextArea1
                    app.TextArea.Value = [app.TextArea.Value, tumorText];

                case 'Percentage volume of Tumor within Brain'
                    a=3;

                    % Finding Percent Volume of Tumor Within Brain
                    percent = ((numOfVoxels_mm2 / numOfVoxels_mm) * 100);

                    % Update TextArea1 Value with proper formatting
                    percentText = sprintf(['---------------Percentage of Tumor in Brain-----------------\n', ...
                        'The Percent volume that the tumor occupies within the brain is: %0.2f%%\n'], ...
                        percent);

                    % Append percentText to the existing resultText in TextArea1
                    app.TextArea.Value = [app.TextArea.Value, percentText];

                case 'Finding the shortest distance from the Tumor to the Brain'
                    a=4;
app.TextArea.Value = [app.TextArea.Value, sprintf('\n---------------Finding the Shortest Distance from the Tumor to the Brain-----------------\n')];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe overall shortest distance between the tumor and brain in milimeters is: %.2f\n', Shortest_Distance_min_overall)];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance between the tumor and brain in the YZ plane in milimeters is: %.2f\n', Shortest_Distance_minYZ)];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance between the tumor and brain in the XZ plane in milimeters is: %.2f\n', Shortest_Distance_minXZ)];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance between the tumor and brain in the XY plane in milimeters is: %.2f\n', Shortest_Distance_minXY)];

app.TextArea.Value = [app.TextArea.Value, sprintf('\n---------------Finding the Angle of the Line of Shortest Distance from the Tumor to the Brain-----------------\n')];

if AngleXY == 0
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the XY plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
else
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the XY plane the angle between the tumor and brain along the line of shortest distance is: %.2f\n', AngleXY)];
end

if AngleXZ == 0
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the XZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
else
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the XZ plane the angle between the tumor and brain along the line of shortest distance is: %.2f\n', AngleXZ)];
end

if AngleYZ == 0
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the YZ plane the angle between the tumor and brain along the line of shortest distance is: ZERO (tumor is on edge of the brain)\n')];
else
    app.TextArea.Value = [app.TextArea.Value, sprintf('In the YZ plane the angle between the tumor and brain (in degrees) along the line of shortest distance is: %.2f\n', AngleYZ)];
end

app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance occurs at slices:\n')];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance occurs in the YZ (Sagital) plane at slices:\n%s', char(join(string(SlicesYZ), ', ')))];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance occurs in the XZ (Coronal) plane at slices:\n%s', char(join(string(SlicesXZ), ', ')))];
app.TextArea.Value = [app.TextArea.Value, sprintf('\nThe shortest distance occurs in the XY (Axial) plane at slices:\n%s', char(join(string(SlicesXY), ', ')))];

                case 'Display montage plot and overlay Volume'
                    a=5;
                    %% Displaying a Montage of all Slices with shortest Distance in the Axial Plane
                    % Specify the slice number range
                    num = numel(SlicesXY);
                    startSlice = SlicesXY(1); % start slice number
                    endSlice = SlicesXY(num); % end slice number

                    if endSlice==startSlice
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        gBrain = strel('disk',1);
                        esBrain=imerode(brainSlice,gBrain);
                        esBrain=brainSlice-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(Tumor,gTumor);
                        esTumor=Tumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,:,startSlice), esBrain(:,:,startSlice));
                        imshow(C)

                    elseif endSlice~=startSlice
                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            brainBin  = Brain > 0;
                            brainSlice = brainBin;

                            % Perform erosion on brainSlice
                            gBrain = strel('disk',1);
                            esBrain=imerode(brainSlice,gBrain);
                            esBrain=brainSlice-esBrain;

                            % Perform erosion on Tumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(Tumor,gTumor);
                            esTumor=Tumor-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor(:,:,sliceNumber), esBrain(:,:,sliceNumber));

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with Shortest Distance in the Axial Plane');
                    end

                    %% Displaying a Montage of all Slices with shortest Distance in the Coronal Plane
                    % Specify the slice number range
                    num = numel(SlicesXZ);
                    startSlice = SlicesXZ(1); % start slice number
                    endSlice = SlicesXZ(num); % end slice number
                    if endSlice==startSlice
                        coronalSlice = squeeze(Brain(:,sliceNumber,:));
                        coronalSliceTumor = squeeze(Tumor(:,sliceNumber,:));
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        coronalSliceBrain = squeeze(brainSlice(:,sliceNumber,:));
                        gBrain = strel('disk',1);
                        esBrain=imerode(coronalSliceBrain,gBrain);
                        esBrain=coronalSliceBrain-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(coronalSliceTumor,gTumor);
                        esTumor=coronalSliceTumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,startSlice,:), esBrain(:,startSlice,:));
                        imshow(C)
                    elseif endSlice~=startSlice

                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            % Extract the coronal slice of brain
                            coronalSliceBrain = squeeze(brainSlice(:,sliceNumber,:));

                            % Perform erosion on coronalSliceBrain
                            gBrain = strel('disk',1);
                            esBrain=imerode(coronalSliceBrain,gBrain);
                            esBrain=coronalSliceBrain-esBrain;

                            % Extract the axial slice of tumor
                            coronalSliceBrain = squeeze(Tumor(:,sliceNumber,:));

                            % Perform erosion on axialSliceTumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(coronalSliceBrain,gTumor);
                            esTumor=coronalSliceBrain-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor, esBrain);

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with shortest Distance in the Coronal Plane');
                    end

                    %% Displaying a Montage of all Slices with shortest Distance in the Sagital Plane
                    % Specify the slice number range
                    num = numel(SlicesYZ);
                    startSlice = SlicesYZ(1); % start slice number
                    endSlice = SlicesYZ(num); % end slice number

                    if endSlice==startSlice
                        sagitalSlice = squeeze(Brain(sliceNumber,:,:));
                        sagitalSliceTumor = squeeze(Tumor(sliceNumber,:,:));
                        brainBin  = Brain>0;
                        brainSlice = brainBin;
                        sagitalSlice = squeeze(brainSlice(sliceNumber,:,:));
                        gBrain = strel('disk',1);
                        esBrain=imerode(sagitalSlice,gBrain);
                        esBrain=sagitalSlice-esBrain;
                        gTumor = strel('disk',1);
                        esTumor=imerode(sagitalSliceTumor,gTumor);
                        esTumor=sagitalSliceTumor-esTumor;

                        figure
                        C = imfuse(esTumor(:,:,:), esBrain(:,:,:));
                        imshow(C)

                    elseif endSlice~=startSlice

                        % Initialize an array to store C values for each slice
                        C_values = cell(numel(startSlice), numel(endSlice));

                        % Iterate through the slice numbers
                        for sliceNumber = startSlice:endSlice
                            % Extract the coronal slice of brain
                            sagitalSliceBrain = squeeze(brainSlice(sliceNumber,:,:));

                            % Perform erosion on coronalSliceBrain
                            gBrain = strel('disk',1);
                            esBrain=imerode(sagitalSliceBrain,gBrain);
                            esBrain=sagitalSliceBrain-esBrain;

                            % Extract the axial slice of tumor
                            sagitalSliceBrain = squeeze(Tumor(sliceNumber,:,:));

                            % Perform erosion on axialSliceTumor
                            gTumor = strel('disk',1);
                            esTumor=imerode(sagitalSliceBrain,gTumor);
                            esTumor=sagitalSliceBrain-esTumor;

                            % Fuse esTumor and esBrain
                            C = imfuse(esTumor, esBrain);

                            % Store C values in the array
                            C_values{sliceNumber} = C;
                        end
                        figure
                        montage(C_values,Indices=startSlice:endSlice);
                        title('Displaying a Montage of all Slices with shortest Distance in the Sagital Plane');
                    end

                    % Opening Interactive Slice Viewer
                    overlayVolume(Brain,Tumor)

            end
                            end
                        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.OverivewLabel = uilabel(app.MenuTab);
            app.OverivewLabel.Text = 'Overivew ';
            app.Label3 = uilabel(app.MenuTab);
            app.Label3.Text = 'The program aims to develop a cutting-edge tool for the detection and surgical planning of brain tumors by automatically detecting the tumors and computing their dimensions, making the process of identifying and locating the tumors faster and more accurate. The program also helps neurosurgeons to plan the most appropriate surgical approach by determining which brain region the tumor is in. One of the key features of the program is measuring 3D coordinates of the tumor, enabling neurosurgeons to create detailed 3D models of the brain, which can be used to plan the surgical approach and visualize the tumor from all angles. The program also outputs a physical 3D-printed model of the tumor that facilitates surgical planning and patient education. This 3D-printed model will enable the surgeon to physically hold and examine the tumor, providing a better understanding of the location and size of the tumor. ';
            app.Label_7 = uilabel(app.MenuTab);
            app.Label_7.Text = '';
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel_2 = uilabel(app.MenuTab);
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel_2.Text = ' NeuroSculpt - Brain Tumor Surgical Planning Tool';
            app.KeyFeaturesLabel = uilabel(app.MenuTab);
            app.KeyFeaturesLabel.Text = 'Key Features ';
            app.Image5 = uiimage(app.MenuTab);
            app.Image5.ImageSource = 'tumor3.JPG';
            app.TumourSegmentationLabel = uilabel(app.MenuTab);
            app.TumourSegmentationLabel.Text = 'Tumour Segmentation:';
            app.Label3_4 = uilabel(app.MenuTab);
            app.Label3_4.Text = 'An image depicting a side-by-side comparison of an original MRI scan and the corresponding segmented tumour image, illustrating the accuracy and clarity of the segmentation process. Alternatively, you could use an icon representing an MRI scan or a stylized brain with highlighted tumour areas.';
            app.Label3_5 = uilabel(app.MenuTab);
            app.Label3_5.Text = 'A color-coded brain atlas image displaying the different brain regions, with the tumour''s partial volume within each region highlighted. This image should demonstrate how the tool identifies the extent of the tumour in various brain areas. An icon of a brain divided into distinct regions can also be used.';
            app.PartialVolumeEstimationLabel = uilabel(app.MenuTab);
            app.PartialVolumeEstimationLabel.Text = 'Partial Volume Estimation:';
            app.Image5_2 = uiimage(app.MenuTab);
            app.Image5_2.ImageSource = 'pv.JPG';
            app.FeatureExtractionLabel = uilabel(app.MenuTab);
            app.FeatureExtractionLabel.Text = 'Feature Extraction';
            app.Label3_6 = uilabel(app.MenuTab);
            app.Label3_6.Text = 'A visual representation of the extracted tumour features, such as a diagram with labeled dimensions, location, and shortest distance to the brain surface. Alternatively, you could use an icon of a magnifying glass over a brain, suggesting the detailed examination of tumour characteristics.';
            app.Image5_3 = uiimage(app.MenuTab);
            app.Image5_3.ImageSource = 'features.2.JPG';
            app.DPrintableModelGenerationLabel = uilabel(app.MenuTab);
            app.DPrintableModelGenerationLabel.Text = '3D-Printable Model Generation';
            app.Label3_7 = uilabel(app.MenuTab);
            app.Label3_7.Text = 'A photograph or rendering of a 3D-printed brain model with the tumour''s negative space, showcasing the tangible representation of the tumour within the brain. You could also use an icon depicting a 3D printer, a brain model, or a combination of both.';
            app.Image5_4 = uiimage(app.MenuTab);
            app.Image5_4.ImageSource = '3d1.JPG';
            app.Image5_5 = uiimage(app.MenuTab);
            app.Image5_5.ImageSource = '3d2.JPG';
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.Image4_3 = uiimage(app.InstructionsTab);
            app.Image4_3.ImageSource = 'Picture7.png';
            app.Image3_3 = uiimage(app.InstructionsTab);
            app.Image3_3.ImageSource = 'istockphoto-1320308217-170667a.jpg';
            app.ShneLabel_3 = uilabel(app.InstructionsTab);
            app.ShneLabel_3.Text = {'Revolutionizing brain tumor detection and treatment, with precision and accuracy.'; ''};
            app.Label_8 = uilabel(app.InstructionsTab);
            app.Label_8.Text = '';
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel_3 = uilabel(app.InstructionsTab);
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel_3.Text = ' NeuroSculpt - Brain Tumor Surgical Planning Tool';
            app.InstructionsLabel = uilabel(app.InstructionsTab);
            app.InstructionsLabel.Text = 'Instructions ';
            app.Step1UploadMRIFilesLabel = uilabel(app.InstructionsTab);
            app.Step1UploadMRIFilesLabel.Text = 'Step 1 - Upload MRI Files:';
            app.Label3_8 = uilabel(app.InstructionsTab);
            app.Label3_8.Text = {'a. Click the "Upload File" button on the homepage or user interface.'; 'b. Locate and select the folder containing the patient''s MRI files (in NIFTI format).'; 'c. The application will automatically scan the folder, identifying and uploading the required FLAIR and T1CE files.'};
            app.Image6 = uiimage(app.InstructionsTab);
            app.Image6.ImageSource = 'png-transparent-blue-arrow-arrow-hand-blue__1_-removebg-preview (1).png';
            app.Step2TumourSegmentationLabel = uilabel(app.InstructionsTab);
            app.Step2TumourSegmentationLabel.Text = 'Step 2 - Tumour Segmentation:';
            app.Label3_9 = uilabel(app.InstructionsTab);
            app.Label3_9.Text = {'a. After uploading the MRI files, click the "Tumour Segmentation" button.'; 'b. The application will call the deep learning-based segmentation script (DeepMedic) to process the MRI data and generate the tumour segmentation output files.'};
            app.Image6_2 = uiimage(app.InstructionsTab);
            app.Image6_2.ImageSource = 'down-removebg-preview.png';
            app.Step3Generate3DPrintableModelLabel = uilabel(app.InstructionsTab);
            app.Step3Generate3DPrintableModelLabel.Text = 'Step 3 - Generate 3D-Printable Model:';
            app.Label3_10 = uilabel(app.InstructionsTab);
            app.Label3_10.Text = {'a. After registering the brain, click the "Download .STL" button.'; 'b. The application will generate an STL file of the patient''s brain with the tumour''s negative space.'; 'c. Save the STL file to your computer and use it to 3D print a physical model of the patient''s brain and tumour for visualization and patient education purposes.'; 'd. Optionally, select the "Slice Brain" option to create axial, coronal, and sagittal slices of the brain model for additional insights into the tumour''s location and extent.'};
            app.Image6_3 = uiimage(app.InstructionsTab);
            app.Image6_3.ImageSource = 'rightside-removebg-preview.png';
            app.Step4FeatureAnalysisLabel = uilabel(app.InstructionsTab);
            app.Step4FeatureAnalysisLabel.Text = 'Step 4 - Feature Analysis:';
            app.Label3_11 = uilabel(app.InstructionsTab);
            app.Label3_11.Text = {'a. Click the "Features" button to access various brain and tumour features.'; 'b. The application will display information such as brain volume, tumour volume, percentage of the tumour within the brain, and the shortest distance from the tumour to the brain surface.'; 'c. Utilize these features to better inform surgical planning and risk assessment.'};
            app.UploadFileTab = uitab(app.TabGroup);
            app.UploadFileTab.Title = 'Upload File';
            app.upload = uibutton(app.UploadFileTab, 'push');
            app.upload.ButtonPushedFcn = createCallbackFcn(app, @uploadPushed, true);
            app.upload.Icon = '410925.png';
            app.upload.Text = '';
            app.Label_5 = uilabel(app.UploadFileTab);
            app.Label_5.Text = '';
            app.Label3_3 = uilabel(app.UploadFileTab);
            app.Label3_3.Text = 'In this section, you will be able to upload your desired image modality in NIFTI format. With our advanced tools, you will be able to easily segment the tumor and view its features. Our program also includes a tumor site detection feature to help you plan the most appropriate surgical approach. Once you have completed the process, you will be able to download a .STL file of your tumor, which can be used for surgical planning and patient education. ';
            app.UploadFileLabel = uilabel(app.UploadFileTab);
            app.UploadFileLabel.Text = 'Upload File ';
            app.stl = uibutton(app.UploadFileTab, 'push');
            app.stl.ButtonPushedFcn = createCallbackFcn(app, @stlPushed, true);
            app.stl.Icon = 'screenshot-2018-07-25-13-43-56.png';
            app.stl.Text = '';
            app.DownloadSTLLabel = uilabel(app.UploadFileTab);
            app.DownloadSTLLabel.Text = 'Download .STL';
            app.partial_vol = uibutton(app.UploadFileTab, 'push');
            app.partial_vol.ButtonPushedFcn = createCallbackFcn(app, @partial_volPushed, true);
            app.partial_vol.Icon = 'maxresdefault.jpg';
            app.partial_vol.Text = '';
            app.PartialVolumeLabel = uilabel(app.UploadFileTab);
            app.PartialVolumeLabel.Text = 'Partial Volume ';
            app.Label_6 = uilabel(app.UploadFileTab);
            app.Label_6.Text = '';
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel = uilabel(app.UploadFileTab);
            app.NeuroSculptBrainTumorSurgicalPlanningToolLabel.Text = ' NeuroSculpt - Brain Tumor Surgical Planning Tool';
            app.STLLayersSpinner_2Label = uilabel(app.UploadFileTab);
            app.STLLayersSpinner_2Label.Text = 'STL Layers';
            app.STLLayersSpinner = uispinner(app.UploadFileTab);
            app.TextArea = uitextarea(app.UploadFileTab);
            app.SelectAtlasDropDownLabel = uilabel(app.UploadFileTab);
            app.SelectAtlasDropDownLabel.Text = 'Select Atlas';
            app.SelectAtlasDropDown = uidropdown(app.UploadFileTab);
            app.SelectAtlasDropDown.Items = {'Juelich Histological Atlas', 'Harvard-Oxford Cortical Structural Atlas', 'Harvard-Oxford Subcortical Structural Atlas', 'JHU ICBM-DTI-81 White Matter labels', 'JHU White Matter tractography Atlas', 'Oxford Thalamic Connectivity Atlas', 'Cerebellar Atlas in MNI152 after FLIRT', 'Cerebellar Atlas in MNI152 after FNIRT', 'MNI Structural Atlas'};
            app.SelectAtlasDropDown.Value = 'Juelich Histological Atlas';
            app.ChangeButton = uibutton(app.UploadFileTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.features = uibutton(app.UploadFileTab, 'push');
            app.features.ButtonPushedFcn = createCallbackFcn(app, @featuresPushed, true);
            app.features.Icon = 'features.jpg';
            app.features.Text = '';
            app.FeaturesLabel = uilabel(app.UploadFileTab);
            app.FeaturesLabel.Text = 'Features ';
            app.tumor_seg = uibutton(app.UploadFileTab, 'push');
            app.tumor_seg.ButtonPushedFcn = createCallbackFcn(app, @tumor_segPushed, true);
            app.tumor_seg.Icon = 'segment.jpg';
            app.tumor_seg.Text = '';
            app.TumorSegmentationLabel = uilabel(app.UploadFileTab);
            app.TumorSegmentationLabel.Text = 'Tumor Segmentation';
            app.FeaturesDropDownLabel = uilabel(app.UploadFileTab);
            app.FeaturesDropDownLabel.Text = 'Features ';
            app.FeaturesDropDown = uidropdown(app.UploadFileTab);
            app.FeaturesDropDown.Items = {'Volume of Brain', 'Volume of Tumor', 'Percentage volume of Tumor within Brain', 'Finding the shortest distance from the Tumor to the Brain', 'Display montage plot and overlay Volume'};
            app.FeaturesDropDown.Value = 'Volume of Brain';
            app.ChangeButton_features = uibutton(app.UploadFileTab, 'push');
            app.ChangeButton_features.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_featuresPushed, true);
            app.ChangeButton_features.Text = 'Change';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end